classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TimeTextArea               matlab.ui.control.TextArea
        TimeTextAreaLabel          matlab.ui.control.Label
        arrivalrate                matlab.ui.control.NumericEditField
        arrivalrateEditFieldLabel  matlab.ui.control.Label
        EVbatterycapacity          matlab.ui.control.NumericEditField
        EVbatterycapacityEditFieldLabel  matlab.ui.control.Label
        powerrating                matlab.ui.control.NumericEditField
        powerratingEditFieldLabel  matlab.ui.control.Label
        numberofports              matlab.ui.control.NumericEditField
        numberofchargingportsEditFieldLabel  matlab.ui.control.Label
        RealTimeDataAnalysisPanel  matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        viewreportButton           matlab.ui.control.Button
        startstopsimulateButton    matlab.ui.control.Button
        EVchargingstationsLabel    matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = private)
        NumberOfPorts = 10 % Number of charging ports
        PowerRating = 50 % [kW] Power rating of a charging port
        BatterCapacity = 40 % [kWh] Total capacity of a car

        mTimer    % timer
        mCounter = 0; % integer counter
        running = 0 % Is the timer running

        station = ChargeStation(10, 50)

        TotalEnergyPerTime
        TotalCostPerTime
        TotalCarsWaitingPerTime
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: numberofports
        function numberofportsValueChanged(app, event)
            app.NumberOfPorts = app.numberofports.Value;
            app.station = ChargeStation(app.NumberOfPorts, app.PowerRating);
        end

        % Button pushed function: startstopsimulateButton
        function startstopsimulateButtonPushed(app, event)

            if app.running == 0
                app.running = 1;
                app.mTimer = timer('TimerFcn', @timerCallback, 'ExecutionMode', 'FixedRate', 'Period', 1);
                start(app.mTimer);
            else
                app.running = 0;
                stop(app.mTimer);
            end
            
            % this is the callback for the timer
            function timerCallback(~, ~)                
                
                for minute=1:60
                    app.mCounter = app.mCounter + 1;
                    app.TimeTextArea.Value = num2str(app.mCounter);
                    new_cars = Car.arriveCars(app.mCounter, 0.5, app.BatterCapacity);
                    app.station = app.station.addCars(new_cars);
                    app.station = app.station.update(app.mCounter);
                    app.TotalEnergyPerTime(app.mCounter) = app.station.getTotalPower();
                    app.TotalCostPerTime(app.mCounter) = app.station.getCost();
                    
                    app.TotalCarsWaitingPerTime(app.mCounter) = app.station.carsWaiting();
                        
                end


                plot(app.UIAxes_3, app.TotalEnergyPerTime);

                plot(app.UIAxes, app.TotalCostPerTime);
                
                plot(app.UIAxes_2, app.TotalCarsWaitingPerTime);
                if app.mCounter == 60*24
                   app.mCounter = 0;
                   stop(app.mTimer);
                end

            end
        end

        % Value changed function: powerrating
        function powerratingValueChanged(app, event)
            app.PowerRating = app.powerrating.Value;
            app.station = ChargeStation(app.NumberOfPorts, app.PowerRating);
        end

        % Value changed function: EVbatterycapacity
        function EVbatterycapacityValueChanged(app, event)
            app.BatterCapacity = app.EVbatterycapacity.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'EV-charging-stations.jpg';
            app.EVchargingstationsLabel = uilabel(app.UIFigure);
            app.EVchargingstationsLabel.Text = 'EV charging stations';
            app.startstopsimulateButton = uibutton(app.UIFigure, 'push');
            app.startstopsimulateButton.ButtonPushedFcn = createCallbackFcn(app, @startstopsimulateButtonPushed, true);
            app.startstopsimulateButton.Text = 'start/stop simulate';
            app.viewreportButton = uibutton(app.UIFigure, 'push');
            app.viewreportButton.Text = 'view report';
            app.RealTimeDataAnalysisPanel = uipanel(app.UIFigure);
            app.RealTimeDataAnalysisPanel.Title = 'Real Time Data Analysis';
            app.UIAxes_3 = uiaxes(app.RealTimeDataAnalysisPanel);
            title(app.UIAxes_3, 'Total Energy transfered')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_2 = uiaxes(app.RealTimeDataAnalysisPanel);
            title(app.UIAxes_2, 'EVs waiting')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.RealTimeDataAnalysisPanel);
            title(app.UIAxes, 'cost')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.numberofchargingportsEditFieldLabel = uilabel(app.UIFigure);
            app.numberofchargingportsEditFieldLabel.Text = 'number of charging ports';
            app.numberofports = uieditfield(app.UIFigure, 'numeric');
            app.numberofports.ValueChangedFcn = createCallbackFcn(app, @numberofportsValueChanged, true);
            app.powerratingEditFieldLabel = uilabel(app.UIFigure);
            app.powerratingEditFieldLabel.Text = 'power rating';
            app.powerrating = uieditfield(app.UIFigure, 'numeric');
            app.powerrating.ValueChangedFcn = createCallbackFcn(app, @powerratingValueChanged, true);
            app.EVbatterycapacityEditFieldLabel = uilabel(app.UIFigure);
            app.EVbatterycapacityEditFieldLabel.Text = 'EV battery capacity';
            app.EVbatterycapacity = uieditfield(app.UIFigure, 'numeric');
            app.EVbatterycapacity.ValueChangedFcn = createCallbackFcn(app, @EVbatterycapacityValueChanged, true);
            app.arrivalrateEditFieldLabel = uilabel(app.UIFigure);
            app.arrivalrateEditFieldLabel.Text = 'arrival rate';
            app.arrivalrate = uieditfield(app.UIFigure, 'numeric');
            app.TimeTextAreaLabel = uilabel(app.UIFigure);
            app.TimeTextAreaLabel.Text = 'Time';
            app.TimeTextArea = uitextarea(app.UIFigure);
            app.TimeTextArea.Value = {'0'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end